classdef plotterapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ClearAllButton                matlab.ui.control.Button
        fileStatusLabel               matlab.ui.control.Label
        PercentageEditField           matlab.ui.control.NumericEditField
        PercentageEditFieldLabel      matlab.ui.control.Label
        MakefileButton                matlab.ui.control.Button
        EndYearDropDown               matlab.ui.control.DropDown
        EndYearDropDownLabel          matlab.ui.control.Label
        StartYearDropDown             matlab.ui.control.DropDown
        StartYearDropDownLabel        matlab.ui.control.Label
        CountriesPanel                matlab.ui.container.Panel
        EnterthefollowingfieldsLabel  matlab.ui.control.Label
        dateLabel                     matlab.ui.control.Label
        generateButton                matlab.ui.control.Button
        fileLabel                     matlab.ui.control.Label
        enterButton                   matlab.ui.control.Button
        filenameField                 matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        Title                         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        validFile = false; % Did we receive a file
        readyToGenerate = false;
        readyToMakeFile = false;
        countries = []; % Description
        selected = []; % Array of country indices
        dates = []; % Dates
        gdpData =  []; % GDP corresponding to the dates
        table = []; 
        checkboxes = [];
        startYear;
        endYear;
        percentage;
    end
    
    methods (Access = private)
        
        function createCountryCheckbox(app,k) %k is the index in the country array
            hgt = app.CountriesPanel.Position(4);
            ckb = uicheckbox('Parent',app.CountriesPanel,...
                'Text',app.countries(k),...
                'Position', [20 hgt - 30*(k+1) 150 22],...
                'Value', 1);
            ckb.ValueChangedFcn = {@app.countryEnable,k};
            app.checkboxes(end+1) = ckb;
        end
        
    end
    
    methods (Access = private)
    
        function countryEnable(app,src,event,index)
            app.selected(index) = ~app.selected(index);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: filenameField
        function filenameFieldValueChanged(app, event)
            value = app.filenameField.Value;
            if (value ~= "")
                app.fileLabel.Text = "Press enter to confirm";
            else 
                app.fileLabel.Text = "Please enter a filename";
            end
        end

        % Button pushed function: enterButton
        function enterButtonPushed(app, event)
            try 
                app.table = readtable(app.filenameField.Value);
                app.fileLabel.Text = "File input success!";
            catch 
                app.fileLabel.Text = "Error reading file!";
                return;
            end
            app.dates = table2array(app.table(:,'Dates'));
            app.startYear = app.dates(1);
            app.endYear = app.dates(end);
            app.StartYearDropDown.Items = strsplit(num2str(app.dates(1):app.dates(end)));
            app.countries = string(app.table.Properties.VariableNames);
            app.countries = app.countries(2:end);
            app.selected = ones(1,length(app.countries));
            raw = app.table{:,2:end};
            for i = 1:length(app.countries)
                createCountryCheckbox(app,i);
            end
            app.gdpData = zeros(size(raw));
            for i = 2:size(raw,1)
                for j = 1:size(raw,2)
                    if(raw(i-1,j) ~= 0)
                        app.gdpData(i,j) = 100*(raw(i,j)-raw(i-1,j))/raw(i-1,j);
                    end
                end
            end
        end

        % Value changed function: StartYearDropDown
        function StartYearDropDownValueChanged(app, event)
            app.startYear = str2double(app.StartYearDropDown.Value);
            app.EndYearDropDown.Items = strsplit(num2str(app.startYear:app.dates(end)));
            app.dateLabel.Text = "Choose end date";
        end

        % Value changed function: EndYearDropDown
        function EndYearDropDownValueChanged(app, event)
            app.endYear = str2double(app.EndYearDropDown.Value);
            app.readyToGenerate = true;
            app.dateLabel.Text = "Ready to generate chart";
        end

        % Button pushed function: generateButton
        function generateButtonPushed(app, event)
            if(app.readyToGenerate)
                cla(app.UIAxes);
                hold(app.UIAxes,"on");
                x = app.startYear:app.endYear;
                first = app.startYear - app.dates(1) + 1;
                last = app.endYear - app.dates(1) + 1;
                leg = legend(app.UIAxes);
                set(leg, 'Interpreter', 'none');
                for i = 1:length(app.countries)
                    if(app.selected(i))
                        y = app.gdpData(first:last,i);
                        plot(app.UIAxes,x,y,'-','DisplayName',app.countries(i));
                    end
                end
                
            end
        end

        % Drop down opening function: StartYearDropDown
        function StartYearDropDownOpening(app, event)
            app.startYear = str2double(app.StartYearDropDown.Value);
            app.EndYearDropDown.Items = strsplit(num2str(app.startYear:app.dates(end)));
            app.dateLabel.Text = "Choose end date";
        end

        % Value changed function: PercentageEditField
        function PercentageEditFieldValueChanged(app, event)
            app.percentage = app.PercentageEditField.Value;
            if(app.percentage == 0)
                app.fileStatusLabel.text = "Please enter a positive %";
            elseif(app.readyToGenerate)
                app.readyToMakeFile = true;
                app.fileStatusLabel.Text = "Ready to make file";
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            cla(app.UIAxes);
            app.fileLabel.Text = "No file selected";
            app.dateLabel.Text = "No dates entered";
            app.fileStatusLabel.Text = "Not ready to make file";
            app.filenameField.Value = "";
            app.PercentageEditField.Value = 0;
            app.StartYearDropDown.Items = {};
            app.EndYearDropDown.Items = {};
            app.validFile = false; % Did we receive a file
            app.readyToGenerate = false;
            app.readyToMakeFile = false;
            app.countries = []; % Description
            app.selected = []; % Array of country indices
            app.dates = []; % Dates
            app.gdpData =  []; % GDP corresponding to the dates
            app.table = []; 
            app.startYear = 0;
            app.endYear = 0;
            app.percentage = 0;
            for i = 1:length(app.checkboxes)
                delete(app.checkboxes(i));
            end
        end

        % Button pushed function: MakefileButton
        function MakefileButtonPushed(app, event)
            years = [];
            countryIndices = [];
            if(app.readyToMakeFile)
                fid = fopen('output.txt','wt');
                fprintf(fid, 'First year GDP growth exceeded %.2f\n', app.percentage);
                for i = 1:length(app.countries)
                    if(app.selected(i))
                        indices = (app.gdpData(:,i) >= app.percentage);
                        value = find(indices,1,'first');
                        if ~isempty(value)
                            countryIndices(end+1) = i;
                            years(end+1) = app.dates(value);
                        end
                    end
                end
                [~,idx] = sort(years);
                years = years(idx);
                countryIndices = countryIndices(idx);
                for j = 1:length(years)
                    fprintf(fid,'%s %d\n', app.countries(countryIndices(j)), years(j));
                end
                
                app.fileStatusLabel.Text = "File saved";
                fclose(fid);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, '% change in GDP from previous year')
            zlabel(app.UIAxes, 'Z')
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'GDP Percentage Growth for Countries over time';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.filenameField = uieditfield(app.UIFigure, 'text');
            app.filenameField.ValueChangedFcn = createCallbackFcn(app, @filenameFieldValueChanged, true);
            app.enterButton = uibutton(app.UIFigure, 'push');
            app.enterButton.ButtonPushedFcn = createCallbackFcn(app, @enterButtonPushed, true);
            app.enterButton.Text = 'Enter';
            app.fileLabel = uilabel(app.UIFigure);
            app.fileLabel.Text = 'No file selected';
            app.generateButton = uibutton(app.UIFigure, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generateButton.Text = 'Generate Chart';
            app.dateLabel = uilabel(app.UIFigure);
            app.dateLabel.Text = 'No dates entered';
            app.EnterthefollowingfieldsLabel = uilabel(app.UIFigure);
            app.EnterthefollowingfieldsLabel.Text = 'Enter the following fields';
            app.CountriesPanel = uipanel(app.UIFigure);
            app.CountriesPanel.Title = 'Countries';
            app.StartYearDropDownLabel = uilabel(app.UIFigure);
            app.StartYearDropDownLabel.Text = 'Start Year';
            app.StartYearDropDown = uidropdown(app.UIFigure);
            app.StartYearDropDown.Items = {};
            app.StartYearDropDown.DropDownOpeningFcn = createCallbackFcn(app, @StartYearDropDownOpening, true);
            app.StartYearDropDown.ValueChangedFcn = createCallbackFcn(app, @StartYearDropDownValueChanged, true);
            app.StartYearDropDown.Value = {};
            app.EndYearDropDownLabel = uilabel(app.UIFigure);
            app.EndYearDropDownLabel.Text = 'End Year';
            app.EndYearDropDown = uidropdown(app.UIFigure);
            app.EndYearDropDown.Items = {};
            app.EndYearDropDown.ValueChangedFcn = createCallbackFcn(app, @EndYearDropDownValueChanged, true);
            app.EndYearDropDown.Value = {};
            app.MakefileButton = uibutton(app.UIFigure, 'push');
            app.MakefileButton.ButtonPushedFcn = createCallbackFcn(app, @MakefileButtonPushed, true);
            app.MakefileButton.Text = 'Make file';
            app.PercentageEditFieldLabel = uilabel(app.UIFigure);
            app.PercentageEditFieldLabel.Text = 'Percentage';
            app.PercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentageEditField.Limits = [0 100];
            app.PercentageEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageEditFieldValueChanged, true);
            app.fileStatusLabel = uilabel(app.UIFigure);
            app.fileStatusLabel.Text = 'Not ready to make file';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotterapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end