classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        FunctionPanel  matlab.ui.container.Panel
        sinxCheckBox   matlab.ui.control.CheckBox
        cosxCheckBox   matlab.ui.control.CheckBox
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: sinxCheckBox
        function sin(app, event)
            if app.sinxCheckBox.Value
                hold(app.UIAxes,'on')
                
                x = linspace(0,4*pi,500);
                y = sin(x);
            
                plot(app.UIAxes,x,y)
                hold(app.UIAxes,'off')
            end
            if not (app.sinxCheckBox.Value) 
                cla(app.UIAxes)
            end
        end

        % Value changed function: cosxCheckBox
        function cos(app, event)
            if app.cosxCheckBox.Value
                hold(app.UIAxes,'on')                
                x = linspace(0,4*pi,500);
                y = cos(x);                 
                plot(app.UIAxes,x,y)
                hold(app.UIAxes,'off')
            end
            if not (app.cosxCheckBox.Value) 
                cla(app.UIAxes)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FunctionPanel = uipanel(app.UIFigure);
            app.FunctionPanel.Title = 'Function';
            app.sinxCheckBox = uicheckbox(app.FunctionPanel);
            app.sinxCheckBox.ValueChangedFcn = createCallbackFcn(app, @sin, true);
            app.sinxCheckBox.Text = ' sin(x)';
            app.cosxCheckBox = uicheckbox(app.FunctionPanel);
            app.cosxCheckBox.ValueChangedFcn = createCallbackFcn(app, @cos, true);
            app.cosxCheckBox.Text = ' cos(x)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graphic')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 5 10 15];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end